classdef STBS_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        StartTestButton       matlab.ui.control.Button
        StopTestButton        matlab.ui.control.Button
        OrientationDataPanel  matlab.ui.container.Panel
        RollEditFieldLabel    matlab.ui.control.Label
        RollEditField         matlab.ui.control.NumericEditField
        PitchEditFieldLabel   matlab.ui.control.Label
        PitchEditField        matlab.ui.control.NumericEditField
        YawEditFieldLabel     matlab.ui.control.Label
        YawEditField          matlab.ui.control.NumericEditField
        Lamp                  matlab.ui.control.Lamp
        Lamp_2                matlab.ui.control.Lamp
    end


    properties (Access = private)
         % Description
         i = 1;
         incoming_data = [];
         orientation_flag = 0;
         time = [];
         tension = [];
         model_400 = [];
         roll = 120;
         pitch = 40;
         yaw = 30;
         ax = gca;
         %ax.YGrid = 'on';
         s1 = 0;
         %s1 = serial('/dev/cu.usbmodem14101', 'BaudRate', 19200);% Verify COM port
    
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            delete(instrfindall);
            app.s1 = serial('/dev/cu.usbmodem14101', 'BaudRate', 19200);% Verify COM port
            fopen(app.s1);
            app.s1.ReadAsyncMode = 'continuous';
            readasync(app.s1);
            
            while app.orientation_flag == 0
                sSerialData = fscanf(app.s1); %read sensor
                flushinput(app.s1);
                t = strsplit(sSerialData,'    '); % same character as the Arduino code
                if  cell2mat(t(1)) == 'Orientation Data'
                    app.orientation_flag = 1;
                    app.roll = t(2);
                    app.pitch = t(3);
                    app.yaw = t(4);
                end
            end
        end

        % Value changed function: RollEditField
        function RollEditFieldValueChanged(app, event)
            app.RollEditField.Value = app.roll;            
        end

        % Value changed function: PitchEditField
        function PitchEditFieldValueChanged(app, event)
            app.PitchEditField.Value = app.pitch;            
        end

        % Value changed function: YawEditField
        function YawEditFieldValueChanged(app, event)
            app.YawEditField.Value = app.yaw;            
        end

        % Button pushed function: StartTestButton
        function StartTestButtonPushed(app, event)
            fprintf(app.s1,'1');
            sSerialData = fscanf(app.s1); %read sensor
            flushinput(app.s1);
            t = strsplit(sSerialData,'    '); % same character as the Arduino code     
            h = animatedline(0,0,'Color','k','LineWidth',1,'Parent',app.UIAxes);
            h1 = animatedline(0,0,'Color','b','LineWidth',1,'Parent',app.UIAxes);
            h2 = animatedline(0,0,'Color','r','LineWidth',1.5, 'LineStyle', '--','Parent',app.UIAxes);
            while cell2mat(t(4)) == '0'
                app.incoming_data(app.i,1) = str2double(t(1)); %change index to correct / time
                app.incoming_data(app.i,2) = str2double(t(2)); %change index to correct / tension
                app.incoming_data(app.i,3) = str2double(t(3)); %change index to correct / model400
        
                app.time(app.i,1) = str2double(t(1)); %change index to correct / time
                app.tension(app.i,1) = str2double(t(2)); %change index to correct / tension
                app.model_400(app.i,1) = str2double(t(3)); %change index to correct / model400
                
                addpoints(h,app.incoming_data(app.i,1),app.incoming_data(app.i,2))% Add points to tension line
                addpoints(h1,app.incoming_data(app.i,1),app.incoming_data(app.i,3))% Add points to model400 line
                addpoints(h2,app.incoming_data(app.i,1),2.2)% Add points to model400 line
                drawnow
                app.i = app.i+1; %increase counter
            end
           
            if cell2mat(t(4)) == '1'
            %% Compile all of the orientation data into one variable
            orientation = table(app.roll, app.pitch, app.yaw);
            
            app.Lamp = 0;
            app.Lamp_2 = 1;
            %% Save all data to excel file
            T = table(app.time, app.tension, app.model_400);
            filename = 'Test_A.xlsx';%create new file name for every test
            
            %% Write table to file 
            writetable(orientation,filename,'Sheet',1)
            writetable(T,filename,'Sheet',2)
            end
            
        end

        % Button pushed function: StopTestButton
        function StopTestButtonPushed(app, event)
            fprintf(app.s1,'1');
            
%             app.Lamp = 0;
%             app.Lamp_2 = 1;
            %% Compile all of the orientation data into one variable
            orientation = table(app.roll, app.pitch, app.yaw);
               
            %% Save all data to excel file
            T = table(app.time, app.tension, app.model_400);
            filename = 'Test_A.xlsx';%create new file name for every test
            
            %% Write table to file 
            writetable(orientation,filename,'Sheet',1)
            writetable(T,filename,'Sheet',2)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StartTestButton = uibutton(app.UIFigure, 'push');
            app.StartTestButton.ButtonPushedFcn = createCallbackFcn(app, @StartTestButtonPushed, true);
            app.StartTestButton.Text = 'Start Test';
            app.StopTestButton = uibutton(app.UIFigure, 'push');
            app.StopTestButton.ButtonPushedFcn = createCallbackFcn(app, @StopTestButtonPushed, true);
            app.StopTestButton.Text = 'Stop Test';
            app.OrientationDataPanel = uipanel(app.UIFigure);
            app.OrientationDataPanel.Title = 'Orientation Data';
            app.RollEditFieldLabel = uilabel(app.OrientationDataPanel);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.OrientationDataPanel, 'numeric');
            app.RollEditField.ValueChangedFcn = createCallbackFcn(app, @RollEditFieldValueChanged, true);
            app.RollEditField.Editable = 'off';
            app.PitchEditFieldLabel = uilabel(app.OrientationDataPanel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.OrientationDataPanel, 'numeric');
            app.PitchEditField.ValueChangedFcn = createCallbackFcn(app, @PitchEditFieldValueChanged, true);
            app.PitchEditField.Editable = 'off';
            app.YawEditFieldLabel = uilabel(app.OrientationDataPanel);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.OrientationDataPanel, 'numeric');
            app.YawEditField.ValueChangedFcn = createCallbackFcn(app, @YawEditFieldValueChanged, true);
            app.YawEditField.Editable = 'off';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = STBS_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end